classdef linearActuatorControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ConnecttoDeviceButton         matlab.ui.control.Button
        PulsePerRevolutionEditFieldLabel  matlab.ui.control.Label
        PulsePerRevolutionEditField   matlab.ui.control.NumericEditField
        RevolutionPerMinuteEditFieldLabel  matlab.ui.control.Label
        RevolutionPerMinuteEditField  matlab.ui.control.NumericEditField
        DistancePerRevolutionmmEditFieldLabel  matlab.ui.control.Label
        DistancePerRevolutionmmEditField  matlab.ui.control.NumericEditField
        DesiredHorizontalMovementmmEditFieldLabel  matlab.ui.control.Label
        DesiredHorizontalMovementmmEditField  matlab.ui.control.NumericEditField
        DesiredVerticalMovementmmEditFieldLabel  matlab.ui.control.Label
        DesiredVerticalMovementmmEditField  matlab.ui.control.NumericEditField
        SendCommandButton             matlab.ui.control.Button
        DisconnectButton              matlab.ui.control.Button
        Label                         matlab.ui.control.Label
    end


    properties (Access = public)
        serialPort;
    end


    methods (Access = private)

        % Button pushed function: ConnecttoDeviceButton
        function ConnecttoDeviceButtonPushed(app, event)
            serialPortList = seriallist;
            if (isempty(serialPortList))
                msgbox('No Available Serial Ports');
            else
            [selectedPort,~] = listdlg('PromptString','Select Serial Port:',...
                'SelectionMode','single',...
                'ListString',serialPortList);
            app.serialPort = serial(serialPortList(selectedPort),'BaudRate',115200);
            fopen(app.serialPort);
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            fclose(app.serialPort);
        end

        % Button pushed function: SendCommandButton
        function SendCommandButtonPushed(app, event)
            pulsePerRevolution = app.PulsePerRevolutionEditField.Value;
            % revolutionPerMinute = app.RevolutionPerMinuteEditField.Value;
            distancePerRevolution = app.DistancePerRevolutionmmEditField.Value;
            
            desiredMovementH = app.DesiredHorizontalMovementmmEditField.Value;
            desiredMovementV = app.DesiredVerticalMovementmmEditField.Value;
            
            nPulseH = round(desiredMovementH / distancePerRevolution * pulsePerRevolution);
            nPulseV = round(desiredMovementV / distancePerRevolution * pulsePerRevolution);
            
            fprintf(app.serialPort,['H' num2str(nPulseH)]);
            % pause(1/revolutionPerMinute*abs(nPulseH)/pulsePerRevolution * 60);
            fprintf(app.serialPort,['V' num2str(nPulseV)]);
            % pause(1/revolutionPerMinute*abs(nPulseV)/pulsePerRevolution * 60);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ConnecttoDeviceButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoDeviceButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoDeviceButtonPushed, true);
            app.ConnecttoDeviceButton.Text = 'Connect to Device';
            app.PulsePerRevolutionEditFieldLabel = uilabel(app.UIFigure);
            app.PulsePerRevolutionEditFieldLabel.Text = 'Pulse Per Revolution';
            app.PulsePerRevolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.PulsePerRevolutionEditField.Value = 3200;
            app.RevolutionPerMinuteEditFieldLabel = uilabel(app.UIFigure);
            app.RevolutionPerMinuteEditFieldLabel.Text = 'Revolution Per Minute';
            app.RevolutionPerMinuteEditField = uieditfield(app.UIFigure, 'numeric');
            app.RevolutionPerMinuteEditField.Value = 60;
            app.DistancePerRevolutionmmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancePerRevolutionmmEditFieldLabel.Text = 'Distance Per Revolution (mm)';
            app.DistancePerRevolutionmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancePerRevolutionmmEditField.Value = 5;
            app.DesiredHorizontalMovementmmEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredHorizontalMovementmmEditFieldLabel.Text = 'Desired Horizontal Movement (mm)';
            app.DesiredHorizontalMovementmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredVerticalMovementmmEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredVerticalMovementmmEditFieldLabel.Text = 'Desired Vertical Movement (mm)';
            app.DesiredVerticalMovementmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SendCommandButton = uibutton(app.UIFigure, 'push');
            app.SendCommandButton.ButtonPushedFcn = createCallbackFcn(app, @SendCommandButtonPushed, true);
            app.SendCommandButton.Text = 'Send Command';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'Created by Muhammet Emin YANIK'; 'Advisor: Prof. Murat TORLAK'; 'The University of Texas at Dallas'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = linearActuatorControl

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end